classdef appGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        R1EditFieldLabel  matlab.ui.control.Label
        R1EditField       matlab.ui.control.NumericEditField
        R2EditFieldLabel  matlab.ui.control.Label
        R2EditField       matlab.ui.control.NumericEditField
        C1EditFieldLabel  matlab.ui.control.Label
        C1EditField       matlab.ui.control.NumericEditField
        C2EditFieldLabel  matlab.ui.control.Label
        C2EditField       matlab.ui.control.NumericEditField
        LoadButton        matlab.ui.control.Button
        CaliberateButton  matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
        UIAxes2           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [fileName,pathName] = uigetfile('.mat','Select Patient file');
            filePath = fullfile(pathName,fileName);
            app.Data = load(filePath);

            t = app.Data.t;
            Paw = app.Data.Paw;
            
            plot(app.UIAxes,t,Paw,'b')
            plot(app.UIAxes2,t,Paw,'b')
            legend(app.UIAxes,'Measured Paw')
            legend(app.UIAxes2,'Measured Paw')
            
            grid(app.UIAxes,"on")
            grid(app.UIAxes2,"on")
            
            app.CaliberateButton.Enable = 'on';
        end

        % Button pushed function: CaliberateButton
        function CaliberateButtonPushed(app, event)
            t = app.Data.t;
            Paw = app.Data.Paw;
            flow = app.Data.measured_flow;
            tau = app.Data.tau;
            
            RC = T2F(flow,Paw);
            R = RC(1);
            C = RC(2);
            Paw1 = FOM(t,flow,R,C);
            hold(app.UIAxes, 'on')
            plot(app.UIAxes,t,Paw1,'r--')
            legend(app.UIAxes,'Measured Paw', 'Simulated Paw')
            
            Prms = T3F(t,flow,Paw,tau);
            R1 = Prms(1);
            R2 = Prms(2);
            C1 = Prms(3);
            C2 = Prms(4);
            PawF = VEM(t,flow,R1,R2,C1,C2);
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2,t,PawF,'r--')
            legend(app.UIAxes2,'Measured Paw', 'Simulated Paw')
            
            app.R1EditField.Value = R1;
            app.R2EditField.Value = R2;
            app.C1EditField.Value = C1;
            app.C2EditField.Value = C2;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.R1EditFieldLabel = uilabel(app.UIFigure);
            app.R1EditFieldLabel.Text = 'R1';
            app.R1EditField = uieditfield(app.UIFigure, 'numeric');
            app.R1EditField.ValueDisplayFormat = '%.4f';
            app.R2EditFieldLabel = uilabel(app.UIFigure);
            app.R2EditFieldLabel.Text = 'R2';
            app.R2EditField = uieditfield(app.UIFigure, 'numeric');
            app.R2EditField.ValueDisplayFormat = '%.4f';
            app.C1EditFieldLabel = uilabel(app.UIFigure);
            app.C1EditFieldLabel.Text = 'C1';
            app.C1EditField = uieditfield(app.UIFigure, 'numeric');
            app.C1EditField.ValueDisplayFormat = '%.2f';
            app.C2EditFieldLabel = uilabel(app.UIFigure);
            app.C2EditFieldLabel.Text = 'C2';
            app.C2EditField = uieditfield(app.UIFigure, 'numeric');
            app.C2EditField.ValueDisplayFormat = '%.2f';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.CaliberateButton = uibutton(app.UIFigure, 'push');
            app.CaliberateButton.ButtonPushedFcn = createCallbackFcn(app, @CaliberateButtonPushed, true);
            app.CaliberateButton.Text = 'Caliberate';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'FOM')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'VEM')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end